classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SelectLapDropDown       matlab.ui.control.DropDown
        SelectLapDropDownLabel  matlab.ui.control.Label
        LoadFileButton          matlab.ui.control.StateButton
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        appData % Description
        apptemp
        apploc
        Data
        selectedLap
    end
    
    methods (Access = private)
        
        function results = PlotData(app)

            cla(app.UIAxes);
            cla(app.UIAxes2);
            maximo=max(app.Data.Brake_Pos.Value);
            normBrake=app.Data.Brake_Pos.Value/maximo;
            hold (app.UIAxes,"on");
            hold(app.UIAxes2,"on");
            pbaspect(app.UIAxes,[1 1 1])
            axis(app.UIAxes,([min(app.Data.Car_Coord_X.Value)-20 max(app.Data.Car_Coord_X.Value)+20 min(app.Data.Car_Coord_Y.Value)-20 max(app.Data.Car_Coord_Y.Value)+20]))
            for k = 1:length(app.Data.Car_Coord_Z.Time)-1
                if(app.Data.Session_Lap_Count.Value(k)==(str2double(app.selectedLap)))  
                 plot(app.UIAxes,[app.Data.Car_Coord_X.Value(k),app.Data.Car_Coord_X.Value(k+1)],[app.Data.Car_Coord_Y.Value(k),app.Data.Car_Coord_Y.Value(k+1)],'Color',[normBrake(k) 1-normBrake(k) 0],LineWidth=2.5);
                 plot(app.UIAxes2,[app.Data.Brake_Pos.Time(k),app.Data.Brake_Pos.Time(k+1)],[app.Data.Brake_Pos.Value(k),app.Data.Brake_Pos.Value(k+1)],'Color',[normBrake(k) 1-normBrake(k) 0])
                end
            end
            title(app.UIAxes,(num2str(str2double(app.selectedLap)+1)+" lap"))

            app.LoadFileButton.Text = "Load File";

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              app.SelectLapDropDown.Items = ["Selec data first"];
              app.selectedLap = {'0'};
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            loc = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
        end

        % Value changed function: LoadFileButton
        function LoadFileButtonValueChanged(app, event)
            value = app.LoadFileButton.Value;
            [file,path] = uigetfile;
            app.LoadFileButton.Text = "Loading file...";
            app.Data = uiimport(file);
            laps = unique(app.Data.Session_Lap_Count.Value);
            cell_array = cell(size(laps));
            for i=1:length(laps)
                cell_array{i} = num2str(laps(i));
            end
            app.SelectLapDropDown.Items = cell_array;
            PlotData(app);
        end

        % Value changed function: SelectLapDropDown
        function SelectLapDropDownValueChanged(app, event)
            app.selectedLap = app.SelectLapDropDown.Value;
            PlotData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadFileButton = uibutton(app.UIFigure, 'state');
            app.LoadFileButton.ValueChangedFcn = createCallbackFcn(app, @LoadFileButtonValueChanged, true);
            app.LoadFileButton.Text = 'Load File';
            app.SelectLapDropDownLabel = uilabel(app.UIFigure);
            app.SelectLapDropDownLabel.Text = 'Select Lap';
            app.SelectLapDropDown = uidropdown(app.UIFigure);
            app.SelectLapDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectLapDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end